classdef Cancellation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        EditField_2                matlab.ui.control.NumericEditField
        NoiseLevelButton           matlab.ui.control.Button
        OperationsLabel            matlab.ui.control.Label
        CloseButton                matlab.ui.control.Button
        StopButton_2               matlab.ui.control.Button
        ListenNewAudioButton       matlab.ui.control.Button
        GenerateButton             matlab.ui.control.Button
        ResetButton                matlab.ui.control.Button
        StopButton                 matlab.ui.control.Button
        ListenAudioButton          matlab.ui.control.Button
        SpectralSubtractionButton  matlab.ui.control.Button
        FxLMSButton                matlab.ui.control.Button
        FeedForwardButton          matlab.ui.control.Button
        LMSButton                  matlab.ui.control.Button
        EditField                  matlab.ui.control.EditField
        RecordButton               matlab.ui.control.Button
        RealTimeActiveNoiseCancellationImplementationLabel  matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            % to record a 5 second audio
            Fs = 8000; %sampling freq
            ch=1; %channel , 1 --> mono , 2--> stereo
            dataType = 'uint8';
            nbits = 16 ; %8--> low resolution, 16--> med , 24--> high resolution
            Nsec = 5; %duration of recording

            %using microphone to record :
            recorder = audiorecorder(Fs,nbits,ch);
            app.EditField.Value = 'Recording Started...';
            recordblocking(recorder,Nsec);
            app.EditField.Value = 'Recording ended...';

            %storing the audio
            x = getaudiodata(recorder, dataType);

            %writing the audio file
            audiowrite('audio.wav',x,Fs);
        end

        % Button pushed function: LMSButton
        function LMSButtonPushed(app, event)
            app.EditField.Value = 'Processing...';
            %least mean square method
            mus = [0.01 0.05 0.1 0.5 1 2];% 0.5 1];
            for j = 1:length(mus)
                for i = 1:100
                    % generate input signal
                    M=128; %buffer size (num filter weights)

                    file_path = 'audio.wav';
                    [x, fs] = audioread(file_path);
                    x = x / max(abs(x)); % Normalize the input signal
                    N = length(x); % Length of input signal

                    muOG = mus(j);

                    % generate known filter coefficients
                    Pz=(0.5*[0:127]).^2;  %linear coefficients
                    %Pz=randn(128,1); %random coefficients
                    ylim = max(Pz)*1.20;
                    ymin = min(Pz)-.2*max(Pz);
                    % generate filtered input signal == desired signal
                    d=conv(Pz,x); %input signal filtered by known filter Pz (primary path)

                    % LMS FOR MAIN ANC %

                    %initalize Wz filter values
                    Wz=zeros(M,1);
                    emean=zeros(N,1);
                    %Make sure that x and d are column vectors
                    x=x(:);
                    d=d(:);
                    %LMS

                    for n=M:N
                        xvec=x(n:-1:n-M+1); %input has to be in reverse orxer
                        %adaptively update mu
                        mu(n) = muOG;
                        e(n)=d(n)-Wz'*xvec; %update error
                        Wz=Wz+mu(n)*xvec*(e(n)); %update filter coefficient
                    end
                    e=e(:);
                    emean = (emean(:)+e);
                end
                emean=(emean)/i;
                if sum(isinf(emean))>0
                    emean(~isinf(emean))=1e3;
                    eall(j,:)=emean;
                elseif max(emean)>1e4
                    for l = 1:length(emean)
                        if abs(emean(l)) > 1e3
                            emean(l)=1e3;
                        end
                    end
                    eall(j,:)=emean;
                else
                    [eall(j,:),q]=(envelope(abs(emean),150,'peaks'));
                end
            end

            % Plot input signal
            output_file_path = 'output.wav';

            % Normalize the filtered signal
            d_normalized = d / max(abs(d));

            % Write the filtered signal to an audio file
            audiowrite(output_file_path, d_normalized, fs);

            app.EditField.Value = 'LMS implemented.';

            plot(app.UIAxes,fftshift(abs(fft(x))));
            title(app.UIAxes,"FFT of Audio");
            xlabel(app.UIAxes,"Frequency")
            ylabel(app.UIAxes,"Amplitude")


        end

        % Button pushed function: FeedForwardButton
        function FeedForwardButtonPushed(app, event)
            app.EditField.Value = 'Processing...';
            %feedforward ANC

            % Parameters
            fs =8000;          % Sampling frequency (Hz)
            T = 5;               % Length of the signal (seconds)
            t = 0:1/fs:T-1/fs;   % Time vector

            % Generate input noise signal
            [input_noise, fs] = audioread('audio.wav');
            input_noise = input_noise(:, 1); % Extract the first channel

            % Define ANC filter parameters
            filter_length = fs;                 % Filter length (set to the sampling frequency)
            desired_response = zeros(size(t));  % Desired response (silence)

            % Perform ANC
            output_signal = zeros(size(t));
            for n = filter_length+1:length(t)
                % Extract the current segment of the input noise
                input_segment = input_noise(n-filter_length+1:n);

                % Apply ANC filter
                output_segment = apply_filter(input_segment);

                % Add the output segment to the output signal
                output_signal(n) = output_segment(end);

                % Update the desired response based on the current output
                desired_response(n) = -output_segment(end);
            end

            % Export the ANC output signal as a WAV file
            output_file = 'output.wav';
            audiowrite(output_file, output_signal, fs);

            % Apply the ANC filter
            function output = apply_filter(input)
                % Here's an example of an IIR filter with coefficients [0.5 0.3] for feedforward path and [0.4] for feedback path
                b = [0.5 0.3];  % Feedforward coefficients
                a = [1 0.4];    % Feedback coefficients
                output = filter(b, a, input);
            end

            app.EditField.Value = 'Feed Forward implemented...';

            plot(app.UIAxes,fftshift(abs(fft(input_noise))));
            title(app.UIAxes,"FFT of Audio");
            xlabel(app.UIAxes,"Frequency")
            ylabel(app.UIAxes,"Amplitude")

        end

        % Button pushed function: FxLMSButton
        function FxLMSButtonPushed(app, event)
            app.EditField.Value = 'Processing...';
            %FxLMS
            %Filtered-x least mean squares
            mus = [0.01 0.05 0.1 0.5 1 2];
            for j = 1:length(mus)
                for i = 1:100
                    % generate input signal
                    muOG=mus(j); %learning rate
                    M=128; %buffer size (num filter weights)

                    [x, fs] = audioread('audio.wav');
                    x = x / max(x);

                    N=length(x); %length of input signal

                    % generate known filter coefficients
                    Pz=0.5*[0:127];  %linear coefficients
                    %Pz=randn(128,1); %random coefficients
                    ylim = max(Pz)*1.20;
                    ymin = min(Pz)-.2*max(Pz);
                    % generate filtered input signal == desired signal
                    d=conv(Pz,x); %input signal filtered by known filter Pz (primary path)

                    % ESTIMATE SECONDARY PATH SIGNAL USING LMS
                    %generate dummy secondary path response Sz
                    Sz = Pz/2;
                    %run known signal through filter
                    yp=conv(Sz,x);
                    %initalize Sz hat values
                    Szh=zeros(M,1);

                    for n=M:N
                        yvec=x(n:-1:n-M+1); %input has to be in reverse orxer has to be
                        %update mu
                        mu(n) = muOG;
                        e(n)=yp(n)-Szh'*yvec; %update error
                        %plot(e)
                        Szh=Szh+mu(n)*yvec*(e(n)); %update filter coefficient
                    end
                    Szh = abs(ifft(1./abs(fft(Szh))));

                    % LMS FOR MAIN ANC %
                    emean=zeros(N,1);
                    %filter input by learned filter to get x prime
                    xp = conv(Szh,x);
                    %initalize Wz filter values
                    Wz=zeros(M,1);
                    %Make sure that x and d are column vectors
                    x=x(:);
                    d=d(:);
                    %LMS
                    for n=M:N
                        xvec=x(n:-1:n-M+1); %input has to be in reverse orxer
                        xpvec=xp(n:-1:n-M+1);
                        %update mu
                        mu(n) = muOG;
                        e(n)=d(n)-Wz'*xvec; %update error
                        %plot(e)
                        Wz=Wz+mu(n)*xpvec*(e(n)); %update filter coefficient
                    end
                    e=e(:);

                    emean = (emean(:)+e);
                end
                emean=(emean)/i;
                if max(emean)>1e4
                    for l = 1:length(emean)
                        if abs(emean(l)) > 1e3
                            emean(l)=1e3;
                        end
                    end
                    eall(j,:)=emean;
                else
                    try
                        [eall(j,:),q]=(envelope(abs(emean),500,'peaks'));
                    catch
                        eall(j,:) = 1e3;
                    end
                end
            end

            audiowrite('output.wav', d, fs);
            app.EditField.Value = 'FxLMS implemented...';

            plot(app.UIAxes,fftshift(abs(fft(x))));
            title(app.UIAxes,"FFT of Audio");
            xlabel(app.UIAxes,"Frequency")
            ylabel(app.UIAxes,"Amplitude")

        end

        % Button pushed function: SpectralSubtractionButton
        function SpectralSubtractionButtonPushed(app, event)
            app.EditField.Value = 'Processing...';
            %spectral subraction method

            % Read the noisy audio signal
            % noisyFilePath = 'audio.wav';
            [noisySignal, fs] = audioread('audio.wav');

            % Parameters for Spectral Subtraction
            frameSize = 512; % Frame size in samples
            overlap = 0.75; % Overlap percentage between frames (0 to 1)
            alpha = 2; % Over-subtraction factor
            beta = 0.02; % Spectral floor (controls the amount of residual noise)

            % Apply Spectral Subtraction
            noisySignal = noisySignal(:, 1); % Consider only the first channel if it's a stereo audio

            % Calculate the number of frames
            frameShift = round(frameSize * (1 - overlap));
            numFrames = floor((length(noisySignal) - frameSize) / frameShift) + 1;

            % Initialize the processed signal
            processedSignal = zeros((numFrames - 1) * frameShift + frameSize, 1);

            for i = 1:numFrames
                % Extract the current frame
                startIndex = (i - 1) * frameShift + 1;
                endIndex = startIndex + frameSize - 1;
                frame = noisySignal(startIndex:endIndex);

                % Perform FFT on the frame
                frameFFT = fft(frame);

                % Calculate the magnitude spectrum and phase
                magSpectrum = abs(frameFFT);
                phase = angle(frameFFT);

                % Estimate the noise spectrum (assumed to be the first frame)
                if i == 1
                    noiseMagSpectrum = magSpectrum;
                end

                % Perform Spectral Subtraction
                processedMagSpectrum = magSpectrum - alpha * noiseMagSpectrum;
                processedMagSpectrum = max(processedMagSpectrum, beta * noiseMagSpectrum);

                % Reconstruct the processed frame using the modified magnitude spectrum and the original phase
                processedFrame = real(ifft(processedMagSpectrum .* exp(1i * phase)));

                % Overlap and add the processed frame to the output signal
                processedSignal(startIndex:endIndex) = processedSignal(startIndex:endIndex) + processedFrame;
            end

            % Normalize the processed signal
            processedSignal = processedSignal / max(abs(processedSignal));

            % Save the processed audio to a file
            outputFilePath = 'output.wav';
            audiowrite(outputFilePath,processedSignal,fs);

            app.EditField.Value = 'Spectral Subtraction implemented.';

            plot(app.UIAxes,fftshift(abs(fft(noisySignal))));
            title(app.UIAxes,"FFT of Audio");
            xlabel(app.UIAxes,"Frequency")
            ylabel(app.UIAxes,"Amplitude")

        end

        % Button pushed function: ListenAudioButton
        function ListenAudioButtonPushed(app, event)
            [x, fs] = audioread('audio.wav');
            sound(x, fs);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            clear sound;
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            [x, fs] = audioread('audio.wav');
            plot(app.UIAxes,x);
            title(app.UIAxes,"Audio");
            xlabel(app.UIAxes,"Time")
            ylabel(app.UIAxes,"Amplitude")
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxes);
            app.EditField.Value = 'Reset Success...';

        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            clear sound;
        end

        % Button pushed function: ListenNewAudioButton
        function ListenNewAudioButtonPushed(app, event)
            [x, fs] = audioread('output.wav');
            sound(x, fs);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: NoiseLevelButton
        function NoiseLevelButtonPushed(app, event)
            [y, fs] = audioread('audio.wav');

            segmentDuration = 1; % Duration of each segment in seconds
            segmentLength = round(segmentDuration * fs); % Length of each segment in samples

            numSegments = floor(length(y) / segmentLength); % Number of segments

            energy = zeros(numSegments, 1); % Array to store the energy of each segment

            % Compute the energy of each segment
            for i = 1:numSegments
                startIndex = (i - 1) * segmentLength + 1;
                endIndex = i * segmentLength;
                segment = y(startIndex:endIndex);
                energy(i) = sum(segment.^2);
            end

            % Find the segment with the lowest energy
            [minEnergy, minIndex] = min(energy);

            % Set the start and end indices of the noise segment
            startSample = (minIndex - 1) * segmentLength + 1;
            endSample = minIndex * segmentLength;

            noiseSegment = y(startSample:endSample);
            noiselevel = mad(noiseSegment);
            % fprintf('Noise Level: %.2f\n', noiselevel);

            app.EditField_2.Value = noiselevel;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Audio')
            xlabel(app.UIAxes, 'Samples')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.RealTimeActiveNoiseCancellationImplementationLabel = uilabel(app.UIFigure);
            app.RealTimeActiveNoiseCancellationImplementationLabel.Text = 'Real - Time Active Noise Cancellation Implementation';
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.LMSButton = uibutton(app.UIFigure, 'push');
            app.LMSButton.ButtonPushedFcn = createCallbackFcn(app, @LMSButtonPushed, true);
            app.LMSButton.Text = 'LMS';
            app.FeedForwardButton = uibutton(app.UIFigure, 'push');
            app.FeedForwardButton.ButtonPushedFcn = createCallbackFcn(app, @FeedForwardButtonPushed, true);
            app.FeedForwardButton.Text = 'FeedForward';
            app.FxLMSButton = uibutton(app.UIFigure, 'push');
            app.FxLMSButton.ButtonPushedFcn = createCallbackFcn(app, @FxLMSButtonPushed, true);
            app.FxLMSButton.Text = 'FxLMS';
            app.SpectralSubtractionButton = uibutton(app.UIFigure, 'push');
            app.SpectralSubtractionButton.ButtonPushedFcn = createCallbackFcn(app, @SpectralSubtractionButtonPushed, true);
            app.SpectralSubtractionButton.Text = 'Spectral Subtraction';
            app.ListenAudioButton = uibutton(app.UIFigure, 'push');
            app.ListenAudioButton.ButtonPushedFcn = createCallbackFcn(app, @ListenAudioButtonPushed, true);
            app.ListenAudioButton.Text = 'Listen Audio';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.ListenNewAudioButton = uibutton(app.UIFigure, 'push');
            app.ListenNewAudioButton.ButtonPushedFcn = createCallbackFcn(app, @ListenNewAudioButtonPushed, true);
            app.ListenNewAudioButton.Text = 'Listen New Audio';
            app.StopButton_2 = uibutton(app.UIFigure, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = 'Stop';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.OperationsLabel = uilabel(app.UIFigure);
            app.OperationsLabel.Text = 'Operations :';
            app.NoiseLevelButton = uibutton(app.UIFigure, 'push');
            app.NoiseLevelButton.ButtonPushedFcn = createCallbackFcn(app, @NoiseLevelButtonPushed, true);
            app.NoiseLevelButton.Text = 'Noise Level';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cancellation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end